classdef imgbr3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        TimepointSliderLabel         matlab.ui.control.Label
        TimepointSlider              matlab.ui.control.Slider
        NextButton                   matlab.ui.control.Button
        PreviousButton               matlab.ui.control.Button
        TempCEditFieldLabel          matlab.ui.control.Label
        TempCEditField               matlab.ui.control.NumericEditField
        RowEditFieldLabel            matlab.ui.control.Label
        RowEditField                 matlab.ui.control.NumericEditField
        ColumnEditFieldLabel         matlab.ui.control.Label
        ColumnEditField              matlab.ui.control.NumericEditField
        MarkasfreezingpointCheckBox  matlab.ui.control.CheckBox
        ButtonGroup                  matlab.ui.container.ButtonGroup
        LiquidButton                 matlab.ui.control.RadioButton
        FrozenButton                 matlab.ui.control.RadioButton
        FreezingpointButton          matlab.ui.control.RadioButton
        imageEditFieldLabel          matlab.ui.control.Label
        imageEditField               matlab.ui.control.NumericEditField
        outofEditFieldLabel          matlab.ui.control.Label
        outofEditField               matlab.ui.control.NumericEditField
        SaveandcloseButton           matlab.ui.control.Button
        JumptofreezingpointButton    matlab.ui.control.Button
    end


    properties (Access = private)
        images_data % The stack of matrices with images
        temp_data   % The array of temperatures
        current_i   % the current image/temp that is viewed
        frozen_status % array of frozen statuses
        images_num  % number of images
    end

    properties (Access = public)
        well_row     % the row of the current well
        well_column  % the column of the current well
        freezing_num % the number of image corresponding to freezing (output)
        freezing_temp% the Temp corresponding to freezing (output)
    end
    
    methods (Access = private)
        
        function adjust_picture(app)
            
            imshow(app.images_data(:,:,app.current_i),[0,255], 'Parent',app.UIAxes);
            app.TempCEditField.Value = app.temp_data(app.current_i);
            app.RowEditField.Value = app.well_row;
            app.ColumnEditField.Value = app.well_column;
            if (app.frozen_status(app.current_i)==0)
                app.FrozenButton.Value = 0;
                app.LiquidButton.Value = 1;
                app.FreezingpointButton.Value = 0;
                app.MarkasfreezingpointCheckBox.Value = 0;
            elseif (app.frozen_status(app.current_i)==1)
                app.FrozenButton.Value = 1;
                app.LiquidButton.Value = 0;
                app.FreezingpointButton.Value = 0;
                app.MarkasfreezingpointCheckBox.Value = 0;
            elseif (app.frozen_status(app.current_i)==2)
                app.FreezingpointButton.Value = 1;
                app.LiquidButton.Value = 0;
                app.LiquidButton.Value = 0;
                app.MarkasfreezingpointCheckBox.Value = 1;
            end
            app.imageEditField.Value = app.current_i;
            app.TimepointSlider.Value = app.current_i;
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data_mat, temp_mat, w_row, w_column)
            app.images_data = data_mat;
            app.temp_data = temp_mat;
            app.images_num = length(temp_mat);
            app.well_row = w_row;
            app.well_column = w_column;
            app.frozen_status = zeros(size(temp_mat));
            app.current_i = 1;
            app.adjust_picture();
            app.outofEditField.Value = app.images_num;
            app.TimepointSlider.Limits = [1,app.images_num];
            app.freezing_num =0;
            app.freezing_temp = NaN;

        end

        % Value changed function: TimepointSlider
        function TimepointSliderValueChanged(app, event)
            app.current_i = round(app.TimepointSlider.Value);
            app.adjust_picture();
            
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            if (app.current_i > 1)
                app.current_i = app.current_i - 1;
            end
            app.TimepointSlider.Value = app.current_i;
            app.adjust_picture();
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
             if (app.current_i < length(app.temp_data))
                 app.current_i = app.current_i + 1;
             end
             app.TimepointSlider.Value = app.current_i;
             app.adjust_picture();
        end

        % Value changed function: MarkasfreezingpointCheckBox
        function MarkasfreezingpointCheckBoxValueChanged(app, event)
            value = app.MarkasfreezingpointCheckBox.Value;
            if value == 1
               app.frozen_status(1:app.current_i-1) = 0;
               app.frozen_status(app.current_i+1:end) = 1;
               app.frozen_status(app.current_i) = 2;
               app.freezing_num = app.current_i;
               app.freezing_temp = app.temp_data(app.current_i);
               app.JumptofreezingpointButton.Text = {'Jump to freezing point', strcat(...
                   '#', num2str(app.freezing_num), ', ', num2str(app.freezing_temp), 'C')};
            elseif value == 0
               app.frozen_status = zeros(size(app.temp_data));
               app.freezing_num = 0;
               app.freezing_temp = NaN;
               app.JumptofreezingpointButton.Text = {'Jump to freezing point'};
            end
            
            app.adjust_picture();
        end

        % Button pushed function: SaveandcloseButton
        function SaveandcloseButtonPushed(app, event)
            app.UIFigureCloseRequest();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            assignin('base','UI_return_num',app.freezing_num);
            assignin('base','UI_return_temp',app.freezing_temp);
            delete(app)
            
        end

        % Value changed function: imageEditField
        function imageEditFieldValueChanged(app, event)
            value = app.imageEditField.Value;
            if value>app.images_num
                value = app.images_num;
            end
            if value<1
                value = 1;
            end
            app.current_i = value;
            app.adjust_picture();
        end

        % Button pushed function: JumptofreezingpointButton
        function JumptofreezingpointButtonPushed(app, event)
            if app.freezing_num >0
                app.current_i = app.freezing_num;
                app.adjust_picture();
            end
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;
            if ((app.current_i + verticalScrollCount)>0)&&((app.current_i + verticalScrollCount)<app.images_num)
                app.current_i = app.current_i + verticalScrollCount;
                app.adjust_picture();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Well')
            app.TimepointSliderLabel = uilabel(app.UIFigure);
            app.TimepointSliderLabel.Text = 'Timepoint';
            app.TimepointSlider = uislider(app.UIFigure);
            app.TimepointSlider.Limits = [0 880];
            app.TimepointSlider.ValueChangedFcn = createCallbackFcn(app, @TimepointSliderValueChanged, true);
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.TempCEditFieldLabel = uilabel(app.UIFigure);
            app.TempCEditFieldLabel.Text = 'Temp (C)';
            app.TempCEditField = uieditfield(app.UIFigure, 'numeric');
            app.TempCEditField.Editable = 'off';
            app.RowEditFieldLabel = uilabel(app.UIFigure);
            app.RowEditFieldLabel.Text = 'Row';
            app.RowEditField = uieditfield(app.UIFigure, 'numeric');
            app.RowEditField.Editable = 'off';
            app.ColumnEditFieldLabel = uilabel(app.UIFigure);
            app.ColumnEditFieldLabel.Text = 'Column';
            app.ColumnEditField = uieditfield(app.UIFigure, 'numeric');
            app.ColumnEditField.Editable = 'off';
            app.MarkasfreezingpointCheckBox = uicheckbox(app.UIFigure);
            app.MarkasfreezingpointCheckBox.ValueChangedFcn = createCallbackFcn(app, @MarkasfreezingpointCheckBoxValueChanged, true);
            app.MarkasfreezingpointCheckBox.Text = 'Mark as freezing point';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.LiquidButton = uiradiobutton(app.ButtonGroup);
            app.LiquidButton.Enable = 'off';
            app.LiquidButton.Text = 'Liquid';
            app.LiquidButton.Value = true;
            app.FrozenButton = uiradiobutton(app.ButtonGroup);
            app.FrozenButton.Enable = 'off';
            app.FrozenButton.Text = 'Frozen';
            app.FreezingpointButton = uiradiobutton(app.ButtonGroup);
            app.FreezingpointButton.Enable = 'off';
            app.FreezingpointButton.Text = 'Freezing point';
            app.imageEditFieldLabel = uilabel(app.UIFigure);
            app.imageEditFieldLabel.Text = 'image #';
            app.imageEditField = uieditfield(app.UIFigure, 'numeric');
            app.imageEditField.ValueChangedFcn = createCallbackFcn(app, @imageEditFieldValueChanged, true);
            app.outofEditFieldLabel = uilabel(app.UIFigure);
            app.outofEditFieldLabel.Text = 'out of';
            app.outofEditField = uieditfield(app.UIFigure, 'numeric');
            app.outofEditField.Editable = 'off';
            app.SaveandcloseButton = uibutton(app.UIFigure, 'push');
            app.SaveandcloseButton.ButtonPushedFcn = createCallbackFcn(app, @SaveandcloseButtonPushed, true);
            app.SaveandcloseButton.Text = 'Save and close';
            app.JumptofreezingpointButton = uibutton(app.UIFigure, 'push');
            app.JumptofreezingpointButton.ButtonPushedFcn = createCallbackFcn(app, @JumptofreezingpointButtonPushed, true);
            app.JumptofreezingpointButton.Text = 'Jump to freezing point';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imgbr3(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end